classdef ecgPlotScreen < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        PWaveAbnormilitesValue   matlab.ui.control.Label
        PWaveAbnormilitesLabel   matlab.ui.control.Label
        TWaveAbnormilitesValue   matlab.ui.control.Label
        TWaveAbnormilitiesLabel  matlab.ui.control.Label
        BPMHeartRateValue        matlab.ui.control.Label
        HeartRateLabel           matlab.ui.control.Label
        YesNoArrhythmia          matlab.ui.control.Label
        YesNoBradycardia         matlab.ui.control.Label
        YesNoTechycardia         matlab.ui.control.Label
        ArrhythmiaLabel          matlab.ui.control.Label
        BradycardiaLabel         matlab.ui.control.Label
        TachycardiaLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plotECG(comp)
           
            load('101m.mat','val');
            ECGsignal = val(1,:) - mean(val(1,:));
            Fs = 360; t = (0:length(ECGsignal)-1) / Fs;
            threshold = max(ECGsignal)/3;

            % QRS Detection
            [pks,locs] = findpeaks(ECGsignal, 'MinPeakHeight', threshold, 'MinPeakDistance', Fs*0.2);
            qrsWidth = round(0.1 * Fs);
            q_locs = max(locs - qrsWidth / 2, 1); 
            s_locs = min(locs + qrsWidth / 2, length(ECGsignal));

            % Arrhythmia Analysis
            RR_intervals = diff(locs)/Fs;
            heart_rates = 60 ./ RR_intervals;
            disp(['Average Heart Rate: ', num2str(mean(heart_rates)), ' BPM']);
            analyzeArrhythmia(t, locs, RR_intervals);

            % ST Segment and PR Interval Analysis
            st_duration = s_locs - q_locs;
            pr_duration = diff(locs) / Fs;
            analyzeSTPR(t, locs, st_duration, pr_duration, Fs);
         
          plotECG(t, ECGsignal, locs, q_locs, s_locs, pks, 'ECG '); 
            
            analyzeHeartRate(locs, Fs);

            t_wave_abnormalities = analyzeTWave(ECGsignal, t, locs, q_locs, Fs);
            
            p_wave_abnormalities = analyzePWave(ECGsignal, t, locs, q_locs, s_locs, Fs);
            
            displayResults(t, locs, t_wave_abnormalities, p_wave_abnormalities);

            % Analyze and plot functions
        function plotECG(t, ECGsignal, locs, q_locs, s_locs, pks, titleText)
             % Plot the entire ECG signal against time on the UIAxes
            plot(comp.UIAxes, t, ECGsignal);
        
            % Mark the R-peaks on the ECG signal with red circles
            hold(comp.UIAxes, 'on');
            plot(comp.UIAxes, t(locs), pks, 'ro', 'MarkerFaceColor', 'r');
        
            % Mark the estimated Q points with black squares
            plot(comp.UIAxes, t(q_locs), ECGsignal(q_locs), 'ks', 'MarkerFaceColor', 'b');
        
            % Mark the estimated S points with green squares
            plot(comp.UIAxes, t(s_locs), ECGsignal(s_locs), 'ks', 'MarkerFaceColor', 'g');
        
            % Set labels for the axes, a title for the plot, and a legend
            xlabel(comp.UIAxes, 'Time (s)');
            ylabel(comp.UIAxes, 'Amplitude');
            title(comp.UIAxes, titleText);
            legend(comp.UIAxes, 'ECG Signal', 'R Peaks', 'Estimated Q Points', 'Estimated S Points');
            hold(comp.UIAxes, 'off'); 
        
        end

    % to analyze Arrhytmia
        function analyzeArrhythmia(t, locs, RR_intervals)
            tachycardia_threshold = 100; % BPM
            bradycardia_threshold = 60; % BPM
            heart_rates = 60 ./ RR_intervals;
        
            tachycardia_indices = find(heart_rates > tachycardia_threshold);
            bradycardia_indices = find(heart_rates < bradycardia_threshold);
            rr_std = std(RR_intervals);
            rr_irregularity_threshold = 0.1; % Customize as needed
        
            if ~isempty(tachycardia_indices)
                comp.YesNoTechycardia.Text = ['Tachycardia detected at times: ', num2str(t(locs(tachycardia_indices)))];
                disp(['Tachycardia detected at times: ', num2str(t(locs(tachycardia_indices)))]);
            else
                comp.YesNoTechycardia.Text = "No tachycardia detected.";
                disp('No tachycardia detected.');
            end
        
            if ~isempty(bradycardia_indices)
                comp.YesNoBradycardia.Text = ['Bradycardia detected at times: ', num2str(t(locs(bradycardia_indices)))];
                disp(['Bradycardia detected at times: ', num2str(t(locs(bradycardia_indices)))]);
            else
                comp.YesNoBradycardia.Text = "No bradycardia detected.";
                disp('No bradycardia detected.');
            end
        
            if rr_std > rr_irregularity_threshold
                comp.YesNoArrhythmia.Text = "Possible arrhythmia (atrial fibrillation) detected.";
                disp('Possible arrhythmia (atrial fibrillation) detected.');
            else
                comp.YesNoArrhythmia.Text = "No significant arrhythmia detected.";
                disp('No significant arrhythmia detected.');
            end
        end

        %analyzeSTPR 
        function analyzeSTPR(t, locs, st_duration, pr_duration, Fs)
            pr_abnormal_threshold = 0.2; % seconds
            st_abnormal_threshold = 0.1; % seconds
        
            for i = 1:length(pr_duration)
                if pr_duration(i) > pr_abnormal_threshold
                    disp(['At ' num2str(t(locs(i))) 's: PR interval prolonged (possible heart block)']);
                elseif pr_duration(i) < 0.12
                    disp(['At ' num2str(t(locs(i))) 's: PR interval too short (possible pre-excitation syndrome)']);
                end
            end
        
            for i = 1:length(st_duration)
                if st_duration(i) > st_abnormal_threshold * Fs
                    disp(['At ' num2str(t(locs(i))) 's: ST segment abnormal (possible myocardial ischemia)']);
                end
            end
        end
                
        % analyze Heart Rate
        function analyzeHeartRate(locs, Fs)
            heart_rates = 60 ./ diff(locs) * Fs;
            heart_rates = heart_rates(heart_rates > 40 & heart_rates < 180);
            comp.BPMHeartRateValue.Text = ['Average Heart Rate: ', num2str(mean(heart_rates)), ' BPM'];
            disp(['Average Heart Rate: ', num2str(mean(heart_rates)), ' BPM']);
            % Add more analysis if needed
        end

        %Analyze twave
        function t_wave_abnormalities = analyzeTWave(ECGsignal, t, locs, q_locs, Fs)
            % Initialize cell array to store T-wave analysis results
            t_wave_abnormalities = cell(length(locs) - 1, 1);
            
            % Define parameters for T-wave morphology analysis (you can adjust these)
            t_wave_duration_threshold = 0.3; % seconds
            t_wave_amplitude_threshold = 0.1; % amplitude units (adjust as needed)
            
            for i = 2:(length(locs)-1) % Iterate up to the second-to-last QRS complex
                % Extract the T-wave segment between current QRS complex and next QRS complex
                t_wave_start_index = q_locs(i);
                t_wave_end_index = q_locs(i + 1);
                t_wave_segment = ECGsignal(t_wave_start_index:t_wave_end_index);
                
                % Measure T-wave duration and amplitude
                t_wave_duration = (t_wave_end_index - t_wave_start_index) / Fs;
                t_wave_amplitude = max(t_wave_segment) - min(t_wave_segment);
                
                % Check for T-wave inversion (example criteria)
                if t_wave_duration > t_wave_duration_threshold && t_wave_amplitude < -t_wave_amplitude_threshold
                    t_wave_abnormalities{i-1} = 'T-wave inversion detected';
                else
                    t_wave_abnormalities{i-1} = 'T-wave within normal limits';
                end
            end
        end

        %analyze pwave
        function p_wave_abnormalities = analyzePWave(ECGsignal, t, locs, q_locs, s_locs, Fs)
            % Initialize cell array to store P-wave analysis results
            p_wave_abnormalities = cell(length(locs) - 1, 1);
            
            % Define parameters for P-wave morphology analysis (you can adjust these)
            p_wave_duration_threshold = 0.12; % seconds
            p_wave_amplitude_threshold = 0.1; % amplitude units (adjust as needed)
            
            for i = 2:(length(locs)-1) % Iterate up to the second-to-last QRS complex
                % Extract the P-wave segment between previous S-wave and current QRS complex
                p_wave_start_index = s_locs(i - 1);
                p_wave_end_index = q_locs(i);
                p_wave_segment = ECGsignal(p_wave_start_index:p_wave_end_index);
                
                % Measure P-wave duration and amplitude
                p_wave_duration = (p_wave_end_index - p_wave_start_index) / Fs;
                p_wave_amplitude = max(p_wave_segment) - min(p_wave_segment);
                
                % Implement your P-wave morphology analysis logic here
                % Example: Check for P-wave shape, amplitude, and duration
                
                % Interpret P-wave abnormalities based on your analysis criteria
                if p_wave_duration > p_wave_duration_threshold && p_wave_amplitude < -p_wave_amplitude_threshold
                    p_wave_abnormalities{i-1} = 'P-wave inversion detected';
                else
                    p_wave_abnormalities{i-1} = 'P-wave within normal limits';
                end
            end
        end

        %display result
        function displayResults(t, locs, t_wave_abnormalities, p_wave_abnormalities)
            disp('T-wave Abnormalities:');
            for i = 1:length(t_wave_abnormalities)
                comp.TWaveAbnormilitesValue.WordWrap = "on";
                comp.TWaveAbnormilitesValue.Text = ['T-wave after QRS complex at ' num2str(t(locs(i + 1))) 's - ' t_wave_abnormalities{i}];
                disp(['T-wave after QRS complex at ' num2str(t(locs(i + 1))) 's - ' t_wave_abnormalities{i}]);
            end
        
            disp('P-wave Abnormalities:');
            for i = 1:length(p_wave_abnormalities)
                comp.PWaveAbnormilitesValue.WordWrap = "on";
                comp.PWaveAbnormilitesValue.Text = ['P-wave before QRS complex at ' num2str(t(locs(i))) 's - ' p_wave_abnormalities{i}];
                disp(['P-wave before QRS complex at ' num2str(t(locs(i))) 's - ' p_wave_abnormalities{i}]);
            end
        end

        %to plot
        % Display the ECG on UIAxes
       %     comp.UIAxes = plot(comp.UIAxes, t, ECGsignal);
        %    xlabel(comp.UIAxes, 'Time (s)');
         %   ylabel(comp.UIAxes, 'Amplitude');
         %   title(comp.UIAxes, 'ECG Signal');

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
          %  plot(comp.UIAxes(t(s_locs),ECGsignal(q_locs)));
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           % plot(comp.UIAxes)
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 963 557];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, 'ECG Plot')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.TachycardiaLabel = uilabel(comp);
            comp.TachycardiaLabel.Text = 'Tachycardia: ';
            comp.BradycardiaLabel = uilabel(comp);
            comp.BradycardiaLabel.Text = 'Bradycardia:';
            comp.ArrhythmiaLabel = uilabel(comp);
            comp.ArrhythmiaLabel.Text = 'Arrhythmia:';
            comp.YesNoTechycardia = uilabel(comp);
            comp.YesNoTechycardia.Text = 'Yes/No';
            comp.YesNoBradycardia = uilabel(comp);
            comp.YesNoBradycardia.Text = 'Yes/No';
            comp.YesNoArrhythmia = uilabel(comp);
            comp.YesNoArrhythmia.Text = 'Yes/No';
            comp.HeartRateLabel = uilabel(comp);
            comp.HeartRateLabel.Text = 'Heart Rate:';
            comp.BPMHeartRateValue = uilabel(comp);
            comp.BPMHeartRateValue.Text = '67.12 BPM';
            comp.TWaveAbnormilitiesLabel = uilabel(comp);
            comp.TWaveAbnormilitiesLabel.Text = 'T-Wave Abnormilities:';
            comp.TWaveAbnormilitesValue = uilabel(comp);
            comp.PWaveAbnormilitesLabel = uilabel(comp);
            comp.PWaveAbnormilitesLabel.Text = 'P-Wave Abnormilites:';
            comp.PWaveAbnormilitesValue = uilabel(comp);
            comp.PWaveAbnormilitesValue.Text = 'Label2';
            % Execute the startup function
            plotECG(comp)
        end
    end
end